classdef ChessRobotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Panel                  matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        OperationModeDropDown  matlab.ui.control.DropDown
        Label_2                matlab.ui.control.Label
        InitializeRobotButton  matlab.ui.control.Button
        SetFenPositionButton   matlab.ui.control.Button
        LogTextArea            matlab.ui.control.TextArea
        Label                  matlab.ui.control.Label
        MovesTable             matlab.ui.control.Table
        TurnLabel              matlab.ui.control.Label
        ConnLamp               matlab.ui.control.Lamp
        HumanColorDropDown     matlab.ui.control.DropDown
        DepthDropDown          matlab.ui.control.DropDown
        ReadyButton            matlab.ui.control.Button
        NewGameButton          matlab.ui.control.Button
        ConnectButton          matlab.ui.control.Button
        StartServerButton      matlab.ui.control.Button
        BoardAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Tcp = []              % will hold tcpclient after Connect
        RxTimer = []          % will hold timer after connect
        RxBuffer string = ""
        Perspective string = "white"
        GameOver logical = false
        MustPressReady logical = false
        PendingFen string = ""
        InRecovery logical = false
        MoveNo double = 1
        PendingWhiteMove string = ""
        LastFen string = ""
        LastLegalFen string = ""
        OperationMode string = "human_vs_robot"
        
        PendingRobotMoveInfo struct = struct()
        PendingRobotUci string = ""
        PendingRobotMoveType string = ""
        PendingRobotScript string = ""

        AutoSendRobotScript logical = true
        AutoConfirmRobotDone logical = false

        UR3client
        UR3Connected logical = false

        RobotBusy logical = false

        ResizeTimer = []
        LastBoardAxesSize double = [0 0]
    end
    
    methods (Access = private)
        
        function ok = connectUR3(app)
            ok = false;

            try
                ip = '192.168.0.10';
                port = 30002;

                if ~isempty(app.UR3client)
                    clear app.UR3client
                end

                app.UR3client = tcpclient(ip, port, "Timeout", 3);
                app.UR3Connected = true;

                app.log("Connected to UR3 at " + ip + ":" + string(port));
                ok = true;

            catch e
                app.UR3Connected = false;
                app.log("UR3 connection failed: " + string(e.message));
            end
        end
        
        function sendRobotInitHome(app)
            if isempty(app.UR3client) || ~app.UR3Connected
                app.log("Cannot initialize UR3: not connected.");
                return;
            end
        
            % TCP chwytaka
            TCP_TOOL = [0.000000, -0.290000, 0.260000, 0.000000, 0.000000, 0.000000];
        
            % Pozycja domowa jointowa
            Q_HOME_deg = [-90, -45, -90, -90, 90, 0];
            Q_HOME = deg2rad(Q_HOME_deg);
        
            % Pozycja domowa kartezjańska odczytana z TP dla tego samego TCP
            P_HOME_lin = [-0.112000, -0.486000, 0.498000, 0.002000, -2.899000, 1.199000];
            
            a_joint = 0.20;
            v_joint = 0.30;
        
            a_lin = 0.05;
            v_lin = 0.02;
            
            script = "";
            script = script + "def robot_init_home():" + newline;
        
            script = script + sprintf( ...
                "  set_tcp(p[%.6f, %.6f, %.6f, %.6f, %.6f, %.6f])" + newline, ...
                TCP_TOOL(1), TCP_TOOL(2), TCP_TOOL(3), ...
                TCP_TOOL(4), TCP_TOOL(5), TCP_TOOL(6));
        
            script = script + "  sleep(0.2)" + newline;
        
            script = script + sprintf( ...
                "  movej([%.6f, %.6f, %.6f, %.6f, %.6f, %.6f], a=%.4f, v=%.4f)" + newline, ...
                Q_HOME(1), Q_HOME(2), Q_HOME(3), Q_HOME(4), Q_HOME(5), Q_HOME(6), ...
                a_joint, v_joint);
        
            script = script + "  sleep(0.2)" + newline;
        
            script = script + sprintf( ...
                "  movel(p[%.6f, %.6f, %.6f, %.6f, %.6f, %.6f], a=%.4f, v=%.4f)" + newline, ...
                P_HOME_lin(1), P_HOME_lin(2), P_HOME_lin(3), ...
                P_HOME_lin(4), P_HOME_lin(5), P_HOME_lin(6), ...
                a_lin, v_lin);
        
            script = script + "  sleep(0.2)" + newline;
            script = script + "end" + newline;
            script = script + "robot_init_home()" + newline;
        
            try
                app.log("Initializing UR3: set TCP, movej HOME, movel HOME_lin...");
                write(app.UR3client, uint8(char(script)), 'uint8');
                app.log("UR3 initialization script sent.");
            catch e
                app.UR3Connected = false;
                app.log("Failed to initialize UR3: " + string(e.message));
            end
        end


        function generateRobotScript(app)
            if isempty(fieldnames(app.PendingRobotMoveInfo))
                app.log("No pending robot move info.");
                return;
            end

            info = app.PendingRobotMoveInfo;

            [regions, squareCenter, ~] = robotRegions();

            pieceParams = robotPieceParams();
            motionParams = robotMotionParams();
            binParams = robotBinParams();
            queenParams = robotQueenParams();

            % Jeżeli człowiek gra czarnymi, robot gra białymi,
            % a fizyczna plansza jest obrócona o 180 stopni.
            humanColor = lower(string(app.HumanColorDropDown.Value));
            boardRotated180 = (humanColor == "black");

            app.log("Generating robot URScript...");
            app.log("  boardRotated180 = " + string(boardRotated180));
            app.log("  move type = " + string(info.type));
            app.log("  uci = " + string(info.uci));

            useHome = (app.OperationMode ~= "computer_vs_computer");

            script = executeRobotMove( ...
                info, boardRotated180, ...
                regions, squareCenter, ...
                pieceParams, motionParams, ...
                binParams, queenParams, ...
                useHome);

            app.PendingRobotScript = script;

            disp("========== GENERATED ROBOT SCRIPT ==========");
            disp(script);
            disp("============================================");

            app.log("Robot URScript generated.");
            app.log("Script length = " + string(strlength(script)) + " chars.");
        end
        
        function sendPendingRobotScript(app)
            if strlength(app.PendingRobotScript) == 0
                app.log("No pending robot script to send.");
                return;
            end

            if isempty(app.UR3client) || ~app.UR3Connected
                app.log("UR3 not connected. Trying to connect...");
                ok = app.connectUR3();

                if ~ok
                    app.log("Cannot send script: UR3 connection failed.");
                    return;
                end
            end

            try
                app.log("Sending pending URScript to UR3...");
                write(app.UR3client, uint8(char(app.PendingRobotScript)), 'uint8');
                app.log("URScript sent to UR3.");
            catch e
                app.UR3Connected = false;
                app.log("Failed to send URScript to UR3: " + string(e.message));
            end
        end

        function log(app, s)
            t = string(datetime("now","Format","HH:mm:ss"));
            app.LogTextArea.Value = [app.LogTextArea.Value; sprintf("[%s] %s", t, s)];
            drawnow limitrate;
        end
    
        function sendCmd(app, cmd)
            if isempty(app.Tcp)
                app.log("TX failed (not connected): " + cmd);
                return;
            end
            write(app.Tcp, uint8([char(cmd), newline]));
            app.log("TX " + cmd);
        end
    
        function startRxTimer(app)
        
            % Stop/delete old timer safely
            try
                if isa(app.RxTimer, "timer") && isvalid(app.RxTimer)
                    stop(app.RxTimer);
                    delete(app.RxTimer);
                end
            catch
                % ignore cleanup errors
            end
            app.RxTimer = [];
        
            % Create new timer
            app.RxTimer = timer( ...
                "ExecutionMode","fixedSpacing", ...
                "Period",0.05, ...
                "TimerFcn",@(~,~)app.onRxTick(), ...
                "ErrorFcn",@(~,e)app.onTimerError(e) );
        
            start(app.RxTimer);
        end
    
        function onRxTick(app)
            try
                if isempty(app.Tcp)
                    return;
                end
        
                n = app.Tcp.NumBytesAvailable;
                if n <= 0
                    return;
                end
        
                data = char(read(app.Tcp, n, "uint8"));
                app.RxBuffer = app.RxBuffer + string(data);
        
                % Split on newline; keep last partial line in buffer
                parts = split(app.RxBuffer, newline);
                app.RxBuffer = parts(end);
        
                for i = 1:numel(parts)-1
                    line = strtrim(parts(i));
                    if line == ""
                        continue;
                    end
                    app.handleMessage(line);
                end
            catch ex
                app.log("onRxTick exception: " + string(ex.message));
                for k = 1:min(5, numel(ex.stack))
                    app.log("  at " + string(ex.stack(k).name) + " (line " + string(ex.stack(k).line) + ")");
                end
                try, stop(app.RxTimer); end
            end
        end

        function onTimerError(app, e)
            % Robust across MATLAB versions (no e.Data.Message assumption)
            try
                if isprop(e, "Data")
                    app.log("Timer error: " + string(e.Data));
                else
                    app.log("Timer error (no Data): " + string(e));
                end
            catch ex
                app.log("Timer error (formatting failed): " + string(ex.message));
            end
        end

        function restartGame(app)
            app.GameOver = false;
            app.TurnLabel.Text = "Turn: ?";
            % If you have other UI elements, reset them here:
            % app.MoveListTextArea.Value = {};
            % app.RobotMoveDoneButton.Enable = "off";
        
            app.sendCmd("NEW_GAME");
            app.sendCmd("GET_FEN");
        end
        
        function shutdownAndClose(app)

            % Stop timer
            try
                if isa(app.RxTimer, "timer") && isvalid(app.RxTimer)
                    stop(app.RxTimer);
                    delete(app.RxTimer);
                end
            catch
            end
            app.RxTimer = [];
        
            % Tell backend to quit, then close MATLAB TCP client
            try
                if ~isempty(app.Tcp)
                    app.sendCmd("QUIT");
                    pause(0.1);
                end
            catch
            end
        
            try
                if ~isempty(app.Tcp)
                    clear app.Tcp;
                end
            catch
            end
            app.Tcp = [];
        
            % Close UR3 TCP
            try
                if ~isempty(app.UR3client)
                    clear app.UR3client;
                end
            catch
            end
            app.UR3client = [];
            app.UR3Connected = false;
        
            % Close UI
            try
                delete(app.UIFigure);
            catch
            end
        end
        
        function ok = isValidFenBasic(app, fen)
            % Very basic FEN check: "<board> <turn> <castling> <ep> <halfmove> <fullmove>"
            try
                parts = split(strtrim(string(fen)), " ");
                if numel(parts) ~= 6
                    ok = false; return;
                end
        
                placement = char(parts(1));
                turn = parts(2);
                castling = parts(3);
                ep = parts(4);
                halfmove = parts(5);
                fullmove = parts(6);
        
                if ~(turn == "w" || turn == "b")
                    ok = false; return;
                end
        
                if castling ~= "-" && ~all(ismember(char(castling), 'KQkq'))
                    ok = false; return;
                end
        
                if ep ~= "-"
                    epc = char(ep);
                    if numel(epc) ~= 2
                        ok = false; return;
                    end
                    if ~(epc(1) >= 'a' && epc(1) <= 'h' && epc(2) >= '1' && epc(2) <= '8')
                        ok = false; return;
                    end
                end
        
                if isnan(str2double(halfmove)) || isnan(str2double(fullmove))
                    ok = false; return;
                end
        
                ranks = strsplit(placement, '/');
                if numel(ranks) ~= 8
                    ok = false; return;
                end
        
                validPieces = 'PNBRQKpnbrqk';
                for i = 1:8
                    row = ranks{i};
                    fileCount = 0;
                    for j = 1:numel(row)
                        ch = row(j);
                        if ch >= '1' && ch <= '8'
                            fileCount = fileCount + (double(ch) - double('0'));
                        elseif any(validPieces == ch)
                            fileCount = fileCount + 1;
                        else
                            ok = false; return;
                        end
                    end
                    if fileCount ~= 8
                        ok = false; return;
                    end
                end
        
                ok = true;
            catch
                ok = false;
            end
        end

        function appendMoveToTable(app, side, uciMove)
            % side: "w" or "b"
            data = app.MovesTable.Data;
            if isempty(data)
                data = cell(0,3);
            end
        
            if side == "w"
                % Start a new row
                data(end+1, :) = {app.MoveNo, char(uciMove), ''};
                app.PendingWhiteMove = uciMove;
            else
                % Fill black move in the last row (or create if missing)
                if size(data,1) == 0
                    data(end+1,:) = {app.MoveNo, '', char(uciMove)};
                else
                    data{end, 3} = char(uciMove);
                end
                app.MoveNo = app.MoveNo + 1;
                app.PendingWhiteMove = "";
            end
        
            app.MovesTable.Data = data;
        end
        
        function info = parseRobotMoveMessage(app, msg)
            % Parses:
            % ROBOT_MOVE uci=e7e5 type=normal piece=P from=e7 to=e5 ...
            %
            % Returns struct with fields:
            % info.uci, info.type, info.piece, info.from, info.to, etc.

            info = struct();
            info.raw = string(msg);

            parts = split(strtrim(string(msg)));

            for i = 2:numel(parts)
                token = string(parts(i));
                eqPos = strfind(char(token), '=');

                if isempty(eqPos)
                    continue;
                end

                key = extractBefore(token, eqPos(1));
                val = extractAfter(token, eqPos(1));

                key = matlab.lang.makeValidName(char(key));
                info.(key) = string(val);
            end

            % Defaults, żeby później nie było błędów przy brakującym polu
            defaults = ["uci","type","piece","piece_color","from","to", ...
                        "capture","captured","captured_color", ...
                        "promotion","ep","rook_from","rook_to", ...
                        "queen_available"];

            for k = 1:numel(defaults)
                f = char(defaults(k));
                if ~isfield(info, f)
                    info.(f) = "none";
                end
            end
        end

        function tf = isRobotToMove(app)
            tf = false;

            if strlength(app.LastFen) == 0
                return;
            end

            side = fenSideToMove(char(app.LastFen)); % "w" albo "b"

            human = lower(string(app.HumanColorDropDown.Value));

            if human == "white"
                robotSide = "b";
            else
                robotSide = "w";
            end

            tf = (side == robotSide);
        end

        function handleMessage(app, msg)
            if app.InRecovery
                if startsWith(msg, "FEN ")
                    fen = extractAfter(msg, 4);
                    drawBoardFromFen(app.BoardAxes, fen, app.Perspective);
                elseif startsWith(msg, "ERROR") || startsWith(msg, "ILLEGAL_MOVE") || startsWith(msg, "HUMAN_MOVE")
                    % ignore
                end
                return;
            end
            % Ignore "Illegal move" notifications while user is setting up pieces
            if app.MustPressReady && startsWith(msg, "ERROR Illegal move:", "IgnoreCase", true)
                return;
            end
        
            % (Optional) also ignore during recovery to avoid log spam
            if app.InRecovery && startsWith(msg, "ERROR Illegal move:", "IgnoreCase", true)
                return;
            end
        
            app.log("RX " + msg);

            if startsWith(msg, "READY_OK")
                app.sendCmd("GET_FEN");
    
            elseif startsWith(msg, "FEN ")
                fen = extractAfter(msg, 4);
                drawBoardFromFen(app.BoardAxes, char(fen), app.Perspective);
                app.LastFen = string(fen);
                % Treat any received FEN as the current "legal" reference position
                % (If your server ever sends illegal/intermediate FENs, we can refine this.)
                app.LastLegalFen = app.LastFen;
                side = fenSideToMove(char(fen));
                if side == "w"
                    app.TurnLabel.Text = "Turn: White";
                elseif side == "b"
                    app.TurnLabel.Text = "Turn: Black";
                else
                    app.TurnLabel.Text = "Turn: ?";
                end
            
            elseif startsWith(msg, "STATUS ")
                app.log("Backend status: " + extractAfter(msg, "STATUS "));

            elseif startsWith(msg, "BOARD_ERROR ")
                app.log("Board status: " + extractAfter(msg, "BOARD_ERROR "));
                app.ConnLamp.Color = [1 0.6 0]; % pomarańczowy: backend działa, ale hardware nie

            elseif startsWith(msg, "BOARD_OK")
                app.log("Board connected.");
                app.ConnLamp.Color = [0 1 0];

            elseif startsWith(msg, "HUMAN_MOVE ")
                if app.GameOver
                    return;
                end

                if app.MustPressReady
                    app.log("Ignoring move: press READY first")
                    return;
                end

                if app.InRecovery
                    return;
                end

                parts = split(msg);
                humanUci = string(parts(2)); % e.g. e2e4 or e7e8q

                % Side to move before the human move was applied
                side = fenSideToMove(char(app.LastFen));

                app.appendMoveToTable(side, humanUci);

                % Refresh board after human move
                app.sendCmd("GET_FEN");

                app.log("Human move detected. Requesting robot response.");
                app.sendCmd("GET_BEST_MOVE");
    
            elseif startsWith(msg, "ROBOT_MOVE ")
                if app.RobotBusy
                    app.log("Ignoring ROBOT_MOVE because robot is still busy.")
                    return;
                end

                app.RobotBusy = true;
                info = app.parseRobotMoveMessage(msg);

                uci = string(info.uci);

                % Save full robot move info for later robot execution layer
                app.PendingRobotMoveInfo = info;
                app.PendingRobotUci = uci;
                app.PendingRobotMoveType = string(info.type);
                
                engineSide = fenSideToMove(char(app.LastFen));  % "w" or "b"
                % Engine just produced a move for the side to play
                if engineSide ~= "w" && engineSide ~= "b"
                    app.log("Cannot determine side to move from FEN. Move table not updated.");
                else
                    app.appendMoveToTable(engineSide, uci);
                end

                app.log("Robot move received:");
                app.log("  uci = " + string(info.uci));
                app.log("  type = " + string(info.type));
                app.log("  piece = " + string(info.piece));
                app.log("  from = " + string(info.from));
                app.log("  to = " + string(info.to));

                if string(info.capture) ~= "none"
                    app.log("  capture = " + string(info.capture));
                    app.log("  captured = " + string(info.captured));
                end

                if string(info.promotion) ~= "none"
                    app.log("  promotion = " + string(info.promotion));
                end

                if string(info.ep) ~= "none"
                    app.log("  en passant captured square = " + string(info.ep));
                end

                if string(info.rook_from) ~= "none"
                    app.log("  rook: " + string(info.rook_from) + " -> " + string(info.rook_to));
                end

                app.generateRobotScript(); 
                if app.AutoSendRobotScript
                    app.sendPendingRobotScript();
                else
                    app.log("AutoSendRobotScript is false. Script generated but not sent.");
                end

            elseif startsWith(msg, "PROMOTION_REQUIRED ")
                choice = uiconfirm(app.UIFigure, ...
                    "Choose promotion piece:", ...
                    "Promotion", ...
                    "Options",["Queen","Rook","Bishop","Knight"], ...
                    "DefaultOption",1, ...
                    "CancelOption",1);
    
                map = containers.Map( ...
                    ["Queen","Rook","Bishop","Knight"], ...
                    ["q","r","b","n"]);
    
                app.sendCmd("PROMOTE " + map(choice));
    
            elseif startsWith(msg, "GAME_OVER ")
                app.GameOver = true;
                result = extractAfter(msg, 10);
                app.log("Game over: " + result);
            
                choice = uiconfirm(app.UIFigure, ...
                    "Game over: " + result + newline + "What do you want to do?", ...
                    "Game Over", ...
                    "Options", ["Restart","Exit"], ...
                    "DefaultOption", 1, ...
                    "CancelOption", 2);
            
                if choice == "Restart"
                    app.restartGame();
                    app.MovesTable.Data = cell(0,3);
                    app.MoveNo = 1;
                    app.PendingWhiteMove = "";
                else
                    app.shutdownAndClose();
                end

            elseif startsWith(msg, "ROBOT_DONE")

                app.log("Robot reported move done.");

                % Robot is physically done, so GUI may accept next ROBOT_MOVE
                app.RobotBusy = false;

                % Confirm the executed move to Python
                app.sendCmd("ROBOT_MOVE_DONE");
                pause(0.05);

                % Refresh board after Python applies the move
                app.sendCmd("GET_FEN");

                app.PendingRobotScript = "";
                app.PendingRobotMoveInfo = struct();
                app.PendingRobotUci = "";
                app.PendingRobotMoveType = "";
                 app.PendingRobotScript = "";

                % In computer-vs-computer mode, request the next engine/robot move
                if app.OperationMode == "computer_vs_computer" && ~app.GameOver
                    pause(0.05);
                    app.log("Computer vs computer mode: requesting next move.");
                    app.sendCmd("GET_BEST_MOVE");
                end
            
           elseif startsWith(msg, "ROBOT_MOVE_FAILED")
                % Robot physically failed to produce the expected board state.
                % Do not confirm another move and do not request another engine move.

                app.log("Robot move failed / physical board mismatch.");

                failInfo = app.parseRobotMoveMessage(msg);

                expectedMove = "unknown";
                reason = "unknown";
                diff = "";

                if isfield(failInfo, "expected")
                    expectedMove = string(failInfo.expected);
                end

                if isfield(failInfo, "reason")
                    reason = string(failInfo.reason);
                end

                if isfield(failInfo, "diff")
                    diff = string(failInfo.diff);
                end

                app.log("  expected = " + expectedMove);
                app.log("  reason = " + reason);

                if strlength(diff) > 0 && diff ~= "none"
                    app.log("  diff = " + diff);
                end

                % Stop accepting/starting robot moves until operator recovery.
                app.RobotBusy = false;
                app.InRecovery = true;
                app.MustPressReady = true;

                % Clear pending robot command/script.
                app.PendingRobotScript = "";
                app.PendingRobotMoveInfo = struct();
                app.PendingRobotUci = "";
                app.PendingRobotMoveType = "";

                % Show last known legal position in GUI.
                if strlength(app.LastLegalFen) > 0
                    drawBoardFromFen(app.BoardAxes, char(app.LastLegalFen), app.Perspective);

                    side = fenSideToMove(char(app.LastLegalFen));
                    if side == "w"
                        app.TurnLabel.Text = "Turn: White";
                    elseif side == "b"
                        app.TurnLabel.Text = "Turn: Black";
                    else
                        app.TurnLabel.Text = "Turn: ?";
                    end
                end

                uialert(app.UIFigure, ...
                    "Robot move failed or the physical board does not match the expected position." + newline + ...
                    "Expected move: " + expectedMove + newline + ...
                    "Fix the pieces according to the displayed position, then press READY.", ...
                    "Robot Move Failed");

            elseif startsWith(msg, "ERROR Illegal move:", "IgnoreCase", true) || startsWith(msg, "ILLEGAL_MOVE")
                % If user is arranging pieces (Set FEN) or in recovery, ignore illegal move popups
                if app.MustPressReady || app.InRecovery
                    %app.log("Ignoring illegal-move notification (waiting for READY): " + msg);
                    return;
                end
            
                choice = uiconfirm(app.UIFigure, ...
                    "Illegal move detected." + newline + ...
                    "Press 'Return to last position' and fix the pieces, then press READY.", ...
                    "Illegal Move", ...
                    "Options", ["Return to last position","Ignore"], ...
                    "DefaultOption", 1, ...
                    "CancelOption", 2);
            
                if choice == "Return to last position"
                    app.InRecovery = true;
                    app.MustPressReady = true;
                    app.log("Recovery mode: fix pieces, then press READY to continue.");
                    % Force GUI back to last legal position immediately
                    if strlength(app.LastLegalFen) > 0
                        drawBoardFromFen(app.BoardAxes, char(app.LastLegalFen), app.Perspective);
                    
                        side = fenSideToMove(char(app.LastLegalFen));
                        if side == "w"
                            app.TurnLabel.Text = "Turn: White";
                        elseif side == "b"
                            app.TurnLabel.Text = "Turn: Black";
                        else
                            app.TurnLabel.Text = "Turn: ?";
                        end
                    end
                    app.sendCmd("GET_FEN");
                end
            elseif startsWith(msg, "ERROR")
                app.log("Server error: " + msg);
            end
        end

        function startResizeTimer(app)

            try
                if isa(app.ResizeTimer, "timer") && isvalid(app.ResizeTimer)
                    stop(app.ResizeTimer);
                    delete(app.ResizeTimer);
                end
            catch
            end

            app.ResizeTimer = timer( ...
                "ExecutionMode", "fixedSpacing", ...
                "Period", 0.25, ...
                "TimerFcn", @(~,~)app.onResizeTimerTick());

            start(app.ResizeTimer);
        end

        function onResizeTimerTick(app)
            try
                oldUnits = app.BoardAxes.Units;
                app.BoardAxes.Units = "pixels";
                pos = app.BoardAxes.Position;
                app.BoardAxes.Units = oldUnits;

                currentSize = [pos(3), pos(4)];

                if any(abs(currentSize - app.LastBoardAxesSize) > 5)
                    app.LastBoardAxesSize = currentSize;

                    if strlength(app.LastFen) > 0
                        drawBoardFromFen(app.BoardAxes, char(app.LastFen), app.Perspective);
                    end
                end

            catch
                % celowo bez logowania, żeby nie spamować przy resize
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            here = fileparts(mfilename('fullpath'));
            addpath(fullfile(here, "matlab"));   

            app.ConnLamp.Color = [1 0 0];  % red initially
            app.TurnLabel.Text = "Turn: ?"; 
            app.OperationModeDropDown.Value = "Human vs Robot";
            app.OperationMode = "human_vs_robot";
                
            app.startResizeTimer();
            % --- Force fullscreen / maximized on launch ---
            try
                app.UIFigure.WindowState = "maximized";
            catch
                % Fallback for older MATLAB versions without WindowState
                try
                    set(app.UIFigure, "Units", "normalized", "Position", [0 0 1 1]);
                catch
                end
            end
        end

        % Button pushed function: StartServerButton
        function StartServerButtonPushed(app, event)
            here = fileparts(mfilename('fullpath'));
            pyFolder = fullfile(here, "python");
            pyFile   = fullfile(pyFolder, "brain_server.py");
            % py = fullfile(here, "brain_server.py");
        
            cmd = sprintf('start "" /min cmd /c "cd /d "%s" && python "%s""', pyFolder, pyFile);
            app.log("Starting server: " + cmd);
            system(cmd);
        
            pause(0.5); 
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                app.Tcp = tcpclient("127.0.0.1", 5000, "Timeout", 5);
                app.ConnLamp.Color = [0 1 0];
                app.log("Connected to the backend server.");
                app.startRxTimer();

                % Optional: ask Python for hardware status
                app.sendCmd("STATUS");

                % Connect to UR3 early, so the first robot move is not delayed
                % Connect to UR3 early, so the first robot move is not delayed
                app.connectUR3();
                
            catch e
                app.ConnLamp.Color = [1 0 0];
                app.log("Connect failed: " + string(e.message));
            end
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.LogTextArea.Value = "";
            app.log("Starting new game.");
            
            modeLabel = string(app.OperationModeDropDown.Value);

            if modeLabel == "Human vs Robot"
                app.OperationMode = "human_vs_robot";
            else
                app.OperationMode = "computer_vs_computer";
            end

            depth = app.DepthDropDown.Value;
            human = app.HumanColorDropDown.Value;
            app.Perspective = lower(string(human)); % show human at bottom
        
            app.sendCmd("CONFIG depth=" + depth + " human=" + human + " mode=" + app.OperationMode);
            app.sendCmd("NEW_GAME");
            app.sendCmd("GET_FEN");
            app.GameOver = false;
            app.MovesTable.Data = cell(0,3);
            app.MoveNo = 1;
            app.PendingWhiteMove = "";

            app.PendingRobotMoveInfo = struct();
            app.PendingRobotUci = "";
            app.PendingRobotMoveType = "";
            app.PendingRobotScript = "";

            app.RobotBusy = false;
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
            % 1) If recovering from illegal move: resume only
            if app.InRecovery
                app.InRecovery = false;
                app.MustPressReady = false;
                app.RobotBusy = false;

                app.log("Resumed after recovery.");
                app.sendCmd("READY");
                app.sendCmd("GET_FEN");

                pause(0.2);

                if app.OperationMode == "computer_vs_computer"
                    app.log("Computer vs computer mode: requesting next move after recovery.");
                    app.sendCmd("GET_BEST_MOVE");

                elseif app.OperationMode == "human_vs_robot"
                    if app.isRobotToMove()
                        app.log("After recovery: robot to move, requesting robot move.");
                        app.sendCmd("GET_BEST_MOVE");
                    else
                        app.log("After recovery: human to move, waiting for human move.");
                    end
                end

                return;
            end
        
            % 2) If we are waiting for READY after setting a FEN: start game from that position
            if app.MustPressReady
                app.log("Starting from custom FEN (NEW_GAME + SET_FEN + READY).");
        
                app.sendCmd("NEW_GAME");
                if strlength(app.PendingFen) > 0
                    app.sendCmd("DEBUG_SET_FEN " + app.PendingFen);
                end
                app.sendCmd("READY");
                app.sendCmd("GET_FEN");

                pause(0.2);

                if app.OperationMode == "computer_vs_computer"
                    app.log("Computer vs computer mode: requesting first move.");
                    app.sendCmd("GET_BEST_MOVE");

                elseif app.OperationMode == "human_vs_robot"
                    if app.isRobotToMove()
                        app.log("Custom FEN: robot to move, requesting robot move.");
                        app.sendCmd("GET_BEST_MOVE");
                    else
                        app.log("Custom FEN: human to move, waiting for human move.");
                    end
                end

                app.MustPressReady = false;
                return;
            end
        
            % 3) Normal case: READY should NOT reset anything
            app.log("READY pressed (no reset).");
            app.sendCmd("READY");
            app.sendCmd("GET_FEN");

            pause(0.2);

            if app.OperationMode == "computer_vs_computer"
                app.log("Computer vs computer mode: requesting first move.");
                app.sendCmd("GET_BEST_MOVE");

            elseif app.OperationMode == "human_vs_robot"
                if app.isRobotToMove()
                 app.log("Custom FEN: robot to move, requesting robot move.");
                   app.sendCmd("GET_BEST_MOVE");
               else
                  app.log("Custom FEN: human to move, waiting for human move.");
               end
            end

            app.MustPressReady = false;
            return;           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.log("Closing application...");

            % Stop RX timer
            try
                if ~isempty(app.RxTimer) && isvalid(app.RxTimer)
                    stop(app.RxTimer);
                    delete(app.RxTimer);
                end
            catch
            end
            
            % Stop resize timer
            try
                if isa(app.ResizeTimer, "timer") && isvalid(app.ResizeTimer)
                    stop(app.ResizeTimer);
                    delete(app.ResizeTimer);
                end
            catch
            end
            app.ResizeTimer = [];

            % Close backend TCP
            try
                if ~isempty(app.Tcp)
                    app.sendCmd("QUIT");
                    pause(0.1);
                end
            catch
            end

            try
                if ~isempty(app.Tcp)
                    clear app.Tcp
                end
            catch
            end

            % Close UR3 TCP
            try
                if ~isempty(app.UR3client)
                    clear app.UR3client
                end
            catch
            end

            delete(app);
        end

        % Button pushed function: SetFenPositionButton
        function SetFenPositionButtonPushed(app, event)
            % Popup input for FEN (cancel-safe) + validation + send to server
        
            defaultFen = "rnbqkbnr/pppppppp/8/8/8/8/PPPPPPPP/RNBQKBNR w KQkq - 0 1";
        
            answer = inputdlg( ...
                {"Enter FEN (e.g. '... w KQkq - 0 1'):"}, ...
                "Set position (FEN)", ...
                [1 120], ...
                {char(defaultFen)}, ...
                "on");
        
            % User pressed Cancel or closed dialog
            if isempty(answer)
                app.log("Set FEN cancelled.");
                return;
            end
        
            fen = strtrim(string(answer{1}));
            if fen == ""
                uialert(app.UIFigure, "Incorrect FEN", "Set FEN");
                return;
            end
        
            % Basic FEN validation (safe, not perfect but prevents breaking)
            if ~app.isValidFenBasic(fen)
                uialert(app.UIFigure, "Incorrect FEN", "Set FEN");
                return;
            end
        app.sendCmd("DEBUG_SET_FEN " + fen);
        app.sendCmd("GET_FEN");
        
        % Require READY before continuing; store for later re-apply after NEW_GAME
        app.PendingFen = fen;
        app.MustPressReady = true;
        app.log("FEN loaded. Press READY to start (will send NEW_GAME + apply FEN + READY).");
        app.MovesTable.Data = cell(0,3);
        app.MoveNo = 1;
        app.PendingWhiteMove = "";
        end

        % Button pushed function: InitializeRobotButton
        function InitializeRobotButtonPushed(app, event)
           if isempty(app.UR3client) || ~app.UR3Connected
                app.log("UR3 not connected. Trying to connect...");
                ok = app.connectUR3();
        
                if ~ok
                    app.log("Cannot initialize robot: UR3 connection failed.");
                    return;
                end
            end
        
            choice = uiconfirm(app.UIFigure, ...
                "Robot will move to HOME and then execute HOME_lin. Continue?", ...
                "Initialize Robot", ...
                "Options", ["Continue", "Cancel"], ...
                "DefaultOption", 2, ...
                "CancelOption", 2);
        
            if choice ~= "Continue"
                app.log("Robot initialization cancelled.");
                return;
            end
        
            app.sendRobotInitHome();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BoardAxes = uiaxes(app.GridLayout);
            app.BoardAxes.XTick = [];
            app.BoardAxes.YTick = [];
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.StartServerButton = uibutton(app.GridLayout2, 'push');
            app.StartServerButton.ButtonPushedFcn = createCallbackFcn(app, @StartServerButtonPushed, true);
            app.StartServerButton.Text = 'Start Server';
            app.ConnectButton = uibutton(app.GridLayout2, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.NewGameButton = uibutton(app.GridLayout2, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.ReadyButton = uibutton(app.GridLayout2, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'READY';
            app.DepthDropDown = uidropdown(app.GridLayout2);
            app.DepthDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.DepthDropDown.Value = '5';
            app.HumanColorDropDown = uidropdown(app.GridLayout2);
            app.HumanColorDropDown.Items = {'white', 'black'};
            app.HumanColorDropDown.Value = 'white';
            app.ConnLamp = uilamp(app.GridLayout2);
            app.TurnLabel = uilabel(app.GridLayout2);
            app.TurnLabel.Text = 'Turn: ?';
            app.MovesTable = uitable(app.GridLayout2);
            app.MovesTable.ColumnName = {'#'; 'White'; 'Black'};
            app.MovesTable.RowName = {};
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.LogTextArea = uitextarea(app.GridLayout2);
            app.LogTextArea.Editable = 'off';
            app.SetFenPositionButton = uibutton(app.GridLayout2, 'push');
            app.SetFenPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetFenPositionButtonPushed, true);
            app.SetFenPositionButton.Text = 'Set Fen Position';
            app.InitializeRobotButton = uibutton(app.GridLayout2, 'push');
            app.InitializeRobotButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeRobotButtonPushed, true);
            app.InitializeRobotButton.Text = 'Initialize Robot';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '';
            app.OperationModeDropDown = uidropdown(app.GridLayout2);
            app.OperationModeDropDown.Items = {'Human vs Robot', 'Computer vs Computer'};
            app.OperationModeDropDown.Value = 'Human vs Robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChessRobotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end